classdef Taller2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        CompletarButton  matlab.ui.control.Button
        uxyEditField     matlab.ui.control.EditField
        uLabel           matlab.ui.control.Label
        Nombres          matlab.ui.control.Label
        Punto3           matlab.ui.control.Label
        Punto2           matlab.ui.control.Label
        Punto1           matlab.ui.control.Label
        VerificarButton  matlab.ui.control.Button
        f_z              matlab.ui.control.EditField
        fzLabel          matlab.ui.control.Label
        vxyEditField     matlab.ui.control.EditField
        vLabel           matlab.ui.control.Label
        harmoniccheck    matlab.ui.control.EditField
        Input_f_xy       matlab.ui.control.EditField
        fLabel           matlab.ui.control.Label
    end

    % Funciones variable compleja
    methods (Access = private)
            
        function is_harmonic = check_harmonic(app, f)
            syms x y;
            % Calcula las segundas derivadas parciales de f 
            % con respecto a x e y
            d2f_dx2 = diff(f, x, 2);
            d2f_dy2 = diff(f, y, 2);
            
            % Verifica si la función es armónica 
            % (comprueba si las segundas derivadas parciales suman cero)
            is_harmonic = simplify(d2f_dx2 + d2f_dy2) == 0;
        end
        
        function u = get_u(app, f)
            % Halla las partes reales de la funcion
            u = real(f);
        end

        function v = get_v(app, f)
            % Halla las partes imaginarias de la funcion
            v = imag(f);
        end
        
        function fz = get_f_z(app, f)
            syms x y z
            % Si f (x, y) es analítica, se tiene que al sustituir en 
            % f (x, y) la x = z y y = 0, entonces la función es 
            % f (x, y) = f (z)
            try
                fz = subs(f, [x, y], [z, 0]);
            catch
                fz = subs(f, [x, y], [0, z*1i]);
            end
        end

        function str = extraer_real(app, str)
            % Buscar coincidencias en la cadena de texto
            match = regexp(str, 'real\((.*?)\)', 'tokens', 'once');
            
            % Extraer la parte dentro de "real()"
            if ~isempty(match)
                str = match{1};
            else
                str = ''; % Si no se encuentra ninguna coincidencia, 
                % asignar un valor por defecto
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VerificarButton
        function VerificarButtonPushed(app, event)
            f = str2sym(app.Input_f_xy.Value);
            % x^3 - 3*x*y^2 + i*3*y*x^2 - i*y^3

            % Chequear que la funcion es harmónica
            is_harmonic = app.check_harmonic(f);

            % Hallar a u y v
            u = get_u(app, f);
            v = get_v(app, f);

            % Hallar f(z)
            fz = app.get_f_z(f);   

            % Mostrar resultados en la caja de texto
            if is_harmonic  
                % Muestra las partes reales e imaginarias
                app.harmoniccheck.Value = 'La función es armónica';
                
                % str_u = app.extraer_real(char(simplify(u)));
                app.uxyEditField.Value = char(simplify(u));
                
                % str_v = app.extraer_real(char(simplify(v)));
                app.vxyEditField.Value = char(simplify(v));
                
                app.f_z.Value = char(simplify(fz));
            else
                app.harmoniccheck.Value = 'La función NO es armónica';
                app.uxyEditField.Value = '';
                app.vxyEditField.Value = '';
                app.f_z.Value = '';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.fLabel = uilabel(app.UIFigure);
            app.fLabel.Text = 'f (x,y)   =';
            app.Input_f_xy = uieditfield(app.UIFigure, 'text');
            app.harmoniccheck = uieditfield(app.UIFigure, 'text');
            app.harmoniccheck.Editable = 'off';
            app.vLabel = uilabel(app.UIFigure);
            app.vLabel.Text = 'v(x,y)  =';
            app.vxyEditField = uieditfield(app.UIFigure, 'text');
            app.fzLabel = uilabel(app.UIFigure);
            app.fzLabel.Text = 'f(z)  =';
            app.f_z = uieditfield(app.UIFigure, 'text');
            app.f_z.Editable = 'off';
            app.VerificarButton = uibutton(app.UIFigure, 'push');
            app.VerificarButton.ButtonPushedFcn = createCallbackFcn(app, @VerificarButtonPushed, true);
            app.VerificarButton.Text = 'Verificar';
            app.Punto1 = uilabel(app.UIFigure);
            app.Punto1.Text = 'Construir una interfaz gráfica que reciba una función armónica o no y que sistema defina si la función es o no armónica y que en caso de que sea armónica muestre que la función es armónica.';
            app.Punto2 = uilabel(app.UIFigure);
            app.Punto2.Text = 'Además esta función armónica puede ser u o v. Si se ingresa a u y ella es armónica, el programa debe mostrar a v y viceversa.';
            app.Punto3 = uilabel(app.UIFigure);
            app.Punto3.Text = 'Además el programa debe mostrar a f(z) en caso de que la función sea armónica.';
            app.Nombres = uilabel(app.UIFigure);
            app.Nombres.Text = 'JUAN JOSÉ CORTÉS';
            app.uLabel = uilabel(app.UIFigure);
            app.uLabel.Text = 'u(x,y)  =';
            app.uxyEditField = uieditfield(app.UIFigure, 'text');
            app.CompletarButton = uibutton(app.UIFigure, 'push');
            app.CompletarButton.Text = 'Completar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Taller2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end